classdef OrbitSimulationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Slider5               matlab.ui.control.Slider
        Slider5Label          matlab.ui.control.Label
        Slider4               matlab.ui.control.Slider
        Slider4Label          matlab.ui.control.Label
        hohmannButton         matlab.ui.control.StateButton
        simulateButtonPushed  matlab.ui.control.Button
        SliderTmax            matlab.ui.control.Slider
        SliderTmaxLabel       matlab.ui.control.Label
        SliderV3              matlab.ui.control.Slider
        SliderV3Label         matlab.ui.control.Label
        SliderV2              matlab.ui.control.Slider
        SliderV2Label         matlab.ui.control.Label
        SliderV1              matlab.ui.control.Slider
        SliderV1Label         matlab.ui.control.Label
        SliderT0              matlab.ui.control.Slider
        SliderT0Label         matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: simulateButtonPushed
        function simulateButtonPushedPushed(app, event)
            %% 获取滑块值
            t0_days = app.SliderT0.Value;          % 天
            v1 = app.SliderV1.Value;               % km/s
            v2 = app.SliderV2.Value;               % km/s
            v3 = app.SliderV3.Value;               % km/s
            tmax_days = app.SliderTmax.Value;      % 天

            if tmax_days<=t0_days
                errordlg('不允许的仿真时间和变轨时间','error'); %如果放置时间不够大输出一个错误对话框
                
                return;
            end


            %% 参数和常数
            t0 = t0_days * 86400;
            tmax = tmax_days * 86400;
 
            mu = 398600;          % 地球引力参数 km³/s²
            R_earth = 6371;            % 地球半径 km
            h = 400;                   % 轨道高度 km（近地轨道）
            r0 = R_earth + h;          % 轨道半径 km
            v_circular = sqrt(mu/r0);  % 圆轨道速度

            %% 初始状态
            x0 = r0; y0 = 0; z0 = 0;              % km
            vx0 = 0; vy0 = v_circular; vz0 = 0;   % km/s
 
            %% 轨道微分方程（是a=-mu/r^3,把他处理成xyz三轴方向）
            odefun = @(t, Y) [Y(4); Y(5); Y(6);
                -mu * Y(1) / (norm(Y(1:3))^3);
                -mu * Y(2) / (norm(Y(1:3))^3);
                -mu * Y(3) / (norm(Y(1:3))^3)];
 
            % ODE选项
            options = odeset('MaxStep',1);
            numPoints = 1000;
 
            %% 变轨前轨道仿真
            if t0 > 1
                tspan_before = linspace(0, t0, numPoints);
                [~, Y_before] = ode23(odefun, tspan_before, [x0; y0; z0; vx0; vy0; vz0], options);
            else
                Y_before = [x0, y0, z0, vx0, vy0, vz0];
            end
 
            %% 变轨点计算
            x_t0 = Y_before(end, 1);
            y_t0 = Y_before(end, 2);
            z_t0 = Y_before(end, 3);
            vx_t0 = Y_before(end, 4) + v1;
            vy_t0 = Y_before(end, 5) + v2;
            vz_t0 = Y_before(end, 6) + v3;
 
            %% 变轨后轨道
            tspan_after = linspace(t0, tmax, numPoints);
            [~, Y_after] = ode23(odefun, tspan_after, [x_t0; y_t0; z_t0; vx_t0; vy_t0; vz_t0], options);
 
            %% 绘图
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
 
            % 绘制地球
            [X_e, Y_e, Z_e] = sphere(30);
            surf(app.UIAxes, X_e*R_earth, Y_e*R_earth, Z_e*R_earth, ...
                'FaceColor', [0.2 0.5 0.8], 'EdgeColor', 'none', ...
                'FaceAlpha', 0.5, 'DisplayName', '地球');
 
            % 变轨前轨道（蓝色）
            if size(Y_before, 1) > 1
                plot3(app.UIAxes, Y_before(:,1), Y_before(:,2), Y_before(:,3), ...
                    'b-', 'LineWidth', 2, 'DisplayName', '变轨前轨道');
            end
 
            % 变轨后轨道（红色）
            plot3(app.UIAxes, Y_after(:,1), Y_after(:,2), Y_after(:,3), ...
                'r-', 'LineWidth', 2, 'DisplayName', '变轨后轨道');
 
            % 标记点
            plot3(app.UIAxes, x0, y0, z0, 'bs', 'MarkerSize', 12, ...
                'MarkerFaceColor', 'b', 'LineWidth', 2, 'DisplayName', '起点');
            plot3(app.UIAxes, x_t0, y_t0, z_t0, 'gp', 'MarkerSize', 18, ...
                'MarkerFaceColor', 'g', 'LineWidth', 2, 'DisplayName', '机动点');
            plot3(app.UIAxes, Y_after(end,1), Y_after(end,2), Y_after(end,3), ...
                'r^', 'MarkerSize', 12, 'MarkerFaceColor', 'r', ...
                'LineWidth', 2, 'DisplayName', '终点');
 
            % 参考圆轨道
            theta = linspace(0, 2*pi, 200);
            plot3(app.UIAxes, r0*cos(theta), r0*sin(theta), zeros(size(theta)), ...
                'k:', 'LineWidth', 1, 'DisplayName', '初始圆轨道');

            % 箭头绘制
            startpoint=[x_t0,y_t0,z_t0];
            finishpoint1=[0,0,0];
            arrowvector1=finishpoint1-startpoint;
            arrowvector2=[1000*v1,1000*v2,1000*v3];
            quiver3(app.UIAxes,startpoint(1), startpoint(2), startpoint(3), arrowvector1(1), arrowvector1(2), arrowvector1(3),...
                'Color', 'yellow', 'LineWidth', 2, 'AutoScale', 'on',...
                'DisplayName',"机动点指向地心");
            quiver3(app.UIAxes,startpoint(1), startpoint(2), startpoint(3), arrowvector2(1), arrowvector2(2), arrowvector2(3),...
                'Color', 'black', 'LineWidth', 2, 'AutoScale', 'on',...
                "DisplayName","速度改变的矢量方向（1000倍大小）");
            
            
            xlabel(app.UIAxes, 'X (km)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(app.UIAxes, 'Y (km)', 'FontSize', 12, 'FontWeight', 'bold');
            zlabel(app.UIAxes, 'Z (km)', 'FontSize', 12, 'FontWeight', 'bold');
            
            dv_total = sqrt(v1^2 + v2^2 + v3^2);
            title(app.UIAxes, sprintf('地球轨道机动 | t_0=%.2f天 | Δv=%.3f km/s', ...
                t0_days, dv_total), 'FontSize', 14);
            
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            legend(app.UIAxes, 'Location', 'bestoutside', 'FontSize', 10);
            view(app.UIAxes, 3);
            hold(app.UIAxes, 'off');
 
            % 自动调整坐标范围
            allX = [Y_before(:,1); Y_after(:,1)];
            allY = [Y_before(:,2); Y_after(:,2)];
            maxRange = max([max(abs(allX)), max(abs(allY)), r0]) * 1.2;
            xlim(app.UIAxes, [-maxRange, maxRange]);
            ylim(app.UIAxes, [-maxRange, maxRange]);
 

        end

        % Value changed function: SliderT0, SliderTmax, SliderV1, 
        % ...and 2 other components
        function sliderValueChanged(app, event)
            app.SliderT0Label.Text = sprintf('机动时间 t₀: %.2f 天', app.SliderT0.Value);
            app.SliderV1Label.Text = sprintf('Δvₓ: %.3f km/s', app.SliderV1.Value);
            app.SliderV2Label.Text = sprintf('Δvᵧ: %.3f km/s', app.SliderV2.Value);
            app.SliderV3Label.Text = sprintf('Δvᵤ: %.3f km/s', app.SliderV3.Value);
            app.SliderTmaxLabel.Text = sprintf('仿真时长: %.2f 天', app.SliderTmax.Value);
        end

        % Value changed function: hohmannButton
        function hohmannTransferButtonPushed(app, event)

    % === 1. 参数定义 ===
    % 获取当前轨道高度
    h1 = app.Slider4.Value;                % 初始高度 km
    h2 = app.Slider5.Value;               % 目标高度 km
  

    mu = 398600;             % 地球引力常数 km^3/s^2
    R_earth = 6371; 
    r1 = R_earth + h1; 
    r2 = R_earth + h2; 

    if r2 <= r1
        try
             uialert(app.UIFigure, '霍曼转移要求目标轨道更高', '输入错误');
        catch
             errordlg('霍曼转移要求目标轨道更高', '输入错误'); 
        end
        return; 
    end

    % === 2. 霍曼转移理论计算 ===
    a_transfer = (r1 + r2) / 2; 

    % v1: 近地点（小圆变大椭圆）
    v1_circ = sqrt(mu / r1); 
    v1_transfer = sqrt(mu * (2/r1 - 1/a_transfer)); 
    dv1 = v1_transfer - v1_circ; % 需要增加的速度量

    % v2: 远地点（大椭圆变大圆）
    v2_transfer = sqrt(mu * (2/r2 - 1/a_transfer)); 
    v2_circ = sqrt(mu / r2); 
    dv2 = v2_circ - v2_transfer; % 需要增加的速度量

    % 时间计算
    t_transfer = pi * sqrt(a_transfer^3 / mu);  % 转移耗时 (秒)
    t0_days = 0.05;           % 初始停泊时间 (减少一点以便看的更清楚)
    
    % === 3. 动力学积分 ===
    % ODE方程: r'' = -mu * r / |r|^3
    odefun = @(t, Y) [Y(4:6); -mu * Y(1:3) / (norm(Y(1:3))^3)]; 

    % 初始状态 (从X轴出发)
    x0 = r1; y0 = 0; z0 = 0; 
    vx0 = 0; vy0 = v1_circ; vz0 = 0; 
    state0 = [x0; y0; z0; vx0; vy0; vz0];

    % 积分设置 (使用ode45精度更高，防止轨道由于误差不闭合)
    opts = odeset('RelTol', 1e-6, 'AbsTol', 1e-9); 

    % --- 第一段：初始圆轨道停泊 (0 -> t0) ---
    t_burn1 = t0_days * 86400; 
    [t_seg1, Y_seg1] = ode45(odefun, [0, t_burn1], state0, opts); 

    % --- 第一次机动 (切向加速) ---
    % 获取变轨前一瞬间的状态
    last_state = Y_seg1(end, :)'; 
    pos_burn1 = last_state(1:3);
    vel_burn1 = last_state(4:6);
    
    % 【核心修改】：计算当前速度方向单位向量
    v_dir = vel_burn1 / norm(vel_burn1); 
    % 沿着速度方向增加 dv1
    vel_new1 = vel_burn1 + dv1 * v_dir; 
    
    state_after_burn1 = [pos_burn1; vel_new1];

    % --- 第二段：转移椭圆轨道 (t0 -> t1) ---
    t_burn2 = t_burn1 + t_transfer; 
    [t_seg2, Y_seg2] = ode45(odefun, [t_burn1, t_burn2], state_after_burn1, opts); 

    % --- 第二次机动 (切向加速) ---
    last_state2 = Y_seg2(end, :)'; 
    pos_burn2 = last_state2(1:3);
    vel_burn2 = last_state2(4:6);
    
    % 【核心修改】：再次计算当前速度方向 (此时在远地点)
    v_dir2 = vel_burn2 / norm(vel_burn2); 
    % 沿着速度方向增加 dv2
    vel_new2 = vel_burn2 + dv2 * v_dir2; 
    
    state_after_burn2 = [pos_burn2; vel_new2];

    % --- 第三段：目标圆轨道 (t1 -> tmax) ---
    t_end = t_burn2 + 10000; % 再飞一段时间
    [t_seg3, Y_seg3] = ode45(odefun, [t_burn2, t_end], state_after_burn2, opts); 

    % 合并数据用于绘图
    Y_total_plot = [Y_seg1; Y_seg2; Y_seg3];

    % === 4. 绘图 ===
    cla(app.UIAxes); 
    hold(app.UIAxes, 'on'); 
    axis(app.UIAxes, 'equal'); 
    grid(app.UIAxes, 'on'); 
   

    % 画地球
    [X_e, Y_e, Z_e] = sphere(50); 
    surf(app.UIAxes, X_e*R_earth, Y_e*R_earth, Z_e*R_earth, ... 
        'FaceColor', [0.2 0.6 0.9], 'EdgeColor', 'none', ... 
        'FaceAlpha', 0.8, 'AmbientStrength', 0.5); 

    % 辅助圆轨道 (为了视觉参考，画完整的圆圈)
    theta = linspace(0, 2*pi, 360);
    plot3(app.UIAxes, r1*cos(theta), r1*sin(theta), zeros(size(theta)), ... 
        'Color', [0.5 0.5 0.5], 'LineStyle', '--', 'DisplayName', 'h1 基准');
    plot3(app.UIAxes, r2*cos(theta), r2*sin(theta), zeros(size(theta)), ... 
        'Color', [0.5 0.5 0.5], 'LineStyle', '--', 'DisplayName', 'h2 基准');

    % 1. 初始段
    plot3(app.UIAxes, Y_seg1(:,1), Y_seg1(:,2), Y_seg1(:,3), ... 
        'c-', 'LineWidth', 1.5, 'DisplayName', '初始轨道'); 
    
    % 2. 转移段
    plot3(app.UIAxes, Y_seg2(:,1), Y_seg2(:,2), Y_seg2(:,3), ... 
        'y-', 'LineWidth', 2, 'DisplayName', '转移轨道'); 
    
    % 3. 目标段
    plot3(app.UIAxes, Y_seg3(:,1), Y_seg3(:,2), Y_seg3(:,3), ... 
        'g-', 'LineWidth', 1.5, 'DisplayName', '目标轨道'); 

    % 标记关键点
    p1 = Y_seg1(1,:);
    p_burn1 = Y_seg2(1,:);
    p_burn2 = Y_seg3(1,:);
    p_end = Y_seg3(end,:);

    plot3(app.UIAxes, p1(1), p1(2), p1(3), 'cs', 'MarkerSize', 8, 'MarkerFaceColor','c', 'DisplayName','Start');
    plot3(app.UIAxes, p_burn1(1), p_burn1(2), p_burn1(3), 'p', 'MarkerSize', 12, ... 
        'MarkerFaceColor','y', 'MarkerEdgeColor','w', 'DisplayName', sprintf('机动1 (+%.3f km/s)', dv1)); 
    plot3(app.UIAxes, p_burn2(1), p_burn2(2), p_burn2(3), 'p', 'MarkerSize', 12, ... 
        'MarkerFaceColor','g', 'MarkerEdgeColor','w', 'DisplayName', sprintf('机动2 (+%.3f km/s)', dv2)); 
    plot3(app.UIAxes, p_end(1), p_end(2), p_end(3), 'g^', 'MarkerSize', 8, 'MarkerFaceColor','g', 'DisplayName','End');

    xlabel(app.UIAxes, 'X (km)'); ylabel(app.UIAxes, 'Y (km)'); zlabel(app.UIAxes, 'Z (km)'); 
    title(app.UIAxes, sprintf('霍曼转移仿真 (Δv total = %.3f km/s)', abs(dv1)+abs(dv2)), 'Color', 'w'); 
    
    view(app.UIAxes, 0, 90); % 默认俯视图看起来更清楚，也可以改成 3D
    legend(app.UIAxes, 'Location', 'northeast', 'TextColor', 'w', 'EdgeColor', 'w'); 
    
    hold(app.UIAxes, 'off'); 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderT0Label = uilabel(app.UIFigure);
            app.SliderT0Label.Text = 'SliderT0';
            app.SliderT0 = uislider(app.UIFigure);
            app.SliderT0.Limits = [0 0.5];
            app.SliderT0.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.SliderV1Label = uilabel(app.UIFigure);
            app.SliderV1Label.Text = 'SliderV1';
            app.SliderV1 = uislider(app.UIFigure);
            app.SliderV1.Limits = [0 10];
            app.SliderV1.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.SliderV2Label = uilabel(app.UIFigure);
            app.SliderV2Label.Text = 'SliderV2';
            app.SliderV2 = uislider(app.UIFigure);
            app.SliderV2.Limits = [0 10];
            app.SliderV2.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.SliderV3Label = uilabel(app.UIFigure);
            app.SliderV3Label.Text = 'SliderV3';
            app.SliderV3 = uislider(app.UIFigure);
            app.SliderV3.Limits = [0 10];
            app.SliderV3.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.SliderTmaxLabel = uilabel(app.UIFigure);
            app.SliderTmaxLabel.Text = 'SliderTmax';
            app.SliderTmax = uislider(app.UIFigure);
            app.SliderTmax.Limits = [0 1];
            app.SliderTmax.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.simulateButtonPushed = uibutton(app.UIFigure, 'push');
            app.simulateButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushedPushed, true);
            app.simulateButtonPushed.Text = 'simulateButtonPushed';
            app.hohmannButton = uibutton(app.UIFigure, 'state');
            app.hohmannButton.ValueChangedFcn = createCallbackFcn(app, @hohmannTransferButtonPushed, true);
            app.hohmannButton.Text = 'hohmannButton';
            app.Slider4Label = uilabel(app.UIFigure);
            app.Slider4Label.Text = 'Slider4';
            app.Slider4 = uislider(app.UIFigure);
            app.Slider4.Limits = [0 1000];
            app.Slider5Label = uilabel(app.UIFigure);
            app.Slider5Label.Text = 'Slider5';
            app.Slider5 = uislider(app.UIFigure);
            app.Slider5.Limits = [0 10000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OrbitSimulationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end